classdef RollerCoasterNathanJones < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Image                     matlab.ui.control.Image
        GatePositionKnob          matlab.ui.control.DiscreteKnob
        GatePositionKnobLabel     matlab.ui.control.Label
        CheckforPassengersButton  matlab.ui.control.Button
        TotalRidersLabel          matlab.ui.control.Label
        WildBlueRollerCoasterRidesLabel  matlab.ui.control.Label
    end

    %{
EGR102RollerCoaster - GUI for a roller coaster 
Authors:    Nathan Jones
Assignment: EGR 102-010 Roller Coaster App
Changed:    20 October 2023
History:     None
Purpose:
 Creates a graphical user interface for Wild Blue Coaster Rides

Notes: None
%}
    properties (Access = private)
        a; % arduino
        s1; % servo motor
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clear app.a;
            clear app.s1;
            app.a = arduino();
            app.s1 = servo(app.a, 'D9', 'MinPulseDuration', 700*10^-6, 'MaxPulseDuration', 2300*10^-6);
            app.TotalRidersLabel.Text = ('Gate up, Loading Can Begin');
        end

        % Button pushed function: CheckforPassengersButton
        function CheckforPassengersButtonPushed(app, event)
            TotalNumRiders = 0;

            while TotalNumRiders < 5


                % Reads voltage from photoresistor
                sensor = readVoltage(app.a, 'A0');

                % Checks if a passenger is present
                threshold = 2.8;
                if sensor <= threshold
                    writePosition(app.s1, 1);
                    app.GatePositionKnob.Value = ('Gate Down');
                    TotalNumRiders = TotalNumRiders + 1;
                    app.TotalRidersLabel.Text = num2str(TotalNumRiders); % Set label to current number of riders.
                    pause(1.25)
                else
                    writePosition(app.s1, 0.5);
                    app.GatePositionKnob.Value = ('Gate Up');
                    pause(1.25)
                end

            end %end of the while loop

            % Gate is locked
            writePosition(app.s1, 0);
            app.GatePositionKnob.Value = ('Gate Locked');

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.WildBlueRollerCoasterRidesLabel = uilabel(app.UIFigure);
            app.WildBlueRollerCoasterRidesLabel.Text = 'Wild Blue Roller Coaster Rides';
            app.TotalRidersLabel = uilabel(app.UIFigure);
            app.TotalRidersLabel.Text = 'Total Riders';
            app.CheckforPassengersButton = uibutton(app.UIFigure, 'push');
            app.CheckforPassengersButton.ButtonPushedFcn = createCallbackFcn(app, @CheckforPassengersButtonPushed, true);
            app.CheckforPassengersButton.Text = 'Check for Passengers';
            app.GatePositionKnobLabel = uilabel(app.UIFigure);
            app.GatePositionKnobLabel.Text = 'Gate Position';
            app.GatePositionKnob = uiknob(app.UIFigure, 'discrete');
            app.GatePositionKnob.Items = {'Gate Up', 'Gate Down', 'Gate Locked'};
            app.GatePositionKnob.Value = 'Gate Up';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Roller Coaster .jpg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RollerCoasterNathanJones

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end